classdef matlab_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Breathing_Detector       matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        DurationsEditFieldLabel  matlab.ui.control.Label
        DurationsEditField       matlab.ui.control.NumericEditField
        ProcessingLampLabel      matlab.ui.control.Label
        ProcessingLamp           matlab.ui.control.Lamp
        StartButton              matlab.ui.control.Button
        BPMTextAreaLabel         matlab.ui.control.Label
        BPMTextArea              matlab.ui.control.TextArea
        ClearButton              matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
    end


    properties (Access = private)
        loop
        count % Description
    end

    properties (Access = public)
        voltage % Description
    end


    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearAllButton(app, event)
            app.ProcessingLamp.Enable='off';
            app.BPMTextArea.Value='0';
            app.DurationsEditField.Value=0;
            cla(app.UIAxes);
            close all;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            val = ((app.DurationsEditField.Value)*100)+2; % Get
            app.ProcessingLamp.Enable='on';
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            %%Variables (Edit yourself)
            SerialPort='COM29'; %serial port
            MaxDeviation = -100;%Maximum Allowable Change from one value to next
            TimeInterval=0.01;%time interval between each input.
            app.loop=val;%count values
            %%Set up the serial port object
            s = serial(SerialPort); %Bluetooth('HC-05',1);
            fopen(s);
            app.voltage = 0;
            
            segment_len=6000;
            segment_advance=1500;
            seg=1;
            pt=1;
            app.count = 2;
            y=zeros(1,segment_len); %segment is 6000 datapoint long
            %signal processing
            Fs = 100;
            Tsamp = 1/Fs;
            x1=y(1:end);
            x1=x1-mean(x1);
            t = (0:length(x1)-1)*Tsamp;
            while ~isequal(app.count,app.loop)
                app.voltage(1)=750;
                %[A,~] = fscanf(s,'%d',1);
                app.voltage(app.count) = fscanf(s,'%f'); %A
                app.voltage(1)=app.voltage(2);
                
                if app.count==pt+segment_advance
                    ln=length(app.voltage(pt:app.count));
                    ave=mean(app.voltage(pt:app.count));
                    app.voltage(pt:app.count)=app.voltage(pt:app.count)-ave;
                    y(4501:6001)=app.voltage(pt:app.count);
                    for i=1:segment_advance+1
                        if y(i)<MaxDeviation
                            y(i)=y(i+1);
                        end
                    end
                    x1=y(1:end);
                    x1=x1-mean(x1);
                    [b,a1] = butter(3,0.3/50,'low');
                    y2=filter(b,a1,x1);
                    t3=t(t>=0);
                    y3=y2(t>=0);
                    y3=y3-mean(y3);
                    
                    hoursPerDay = 35;
                    coeff24hMA = ones(1, hoursPerDay)/hoursPerDay;
                    y4= filter(coeff24hMA, 1, y3);
                    
                    n=1:length(y4);
                    N=(max(y4)-mean(y4)).*sin((2*3.1416*0.75/300).*n);
                    Z=zeros(1,length(y4));
                    Z(1:301)=1;
                    N=N.*Z;
                    [acor,lag] = xcorr(y4,N);
                    acor2=acor(lag>=0);
                    
                    [~, envLow] = envelope(acor2,300,'peak');
                    %envLow(envLow<min(acor2))=mean(acor2);
                    acor3=acor2-envLow;
                    acor3(acor3<0)=0;
                    acor3=acor3/rms(acor3);
                    acor3=((acor3).^2)./2;
                    acor3(acor3<0.5)=0;
                    acor3=acor3.*10;
                    acor3(acor3<=mean(acor3)/4)=acor3(acor3<=mean(acor3)/4)./10;
                    acor3(acor3>=mean(acor3)*2)=(acor3(acor3>=mean(acor3)*2)./20)+mean(acor3)*2;
                    
                    for in=fliplr(length(acor3)-500:length(acor3))
                        if acor3(in)>mean(acor3)/4 && acor3(in)>acor3(in-1)
                            acor3(in)=0;
                        else
                            break;
                        end
                    end
                    acor3=smooth(acor3);
                    plot(app.UIAxes,t3,acor3);
                    hold(app.UIAxes,'on');
                    kpre=1;index=0;
                    for k=2:length(acor3)-1
                        if (acor3(k)>acor3(k-1)&& acor3(k)>acor3(k+1)&& acor3(k)>mean(acor3)/2 && abs(t3(k)-t3(kpre))>=1)
                            plot(app.UIAxes,t3(k),acor3(k),'rx');
                            %BPM=BPM+(60/(abs(t3(k)-t3(kpre))));
                            index=index+1;
                            kpre=k;
                        end
                    end
                    hold(app.UIAxes,'off');
                    BPM=(index*4)/seg;
                    app.BPMTextArea.Value=num2str(BPM);
                    if seg>4
                        seg=4;
                    else
                        seg=seg+1;
                    end
                    
                    
                    y(1:4501)=y(1501:6001);
                    pt=app.count;
                end
                
                pause(TimeInterval);
                app.count = app.count +1;
            end
            for i=1:length(app.voltage)
                if app.voltage(i)<MaxDeviation
                    app.voltage(i)=app.voltage(i+1);
                end
            end
            assignin('base','value',app.voltage);
            app.ProcessingLamp.Enable='off';
            fclose(s);
            delete(s);
            clear s;
        end

        % Button pushed function: StopButton
        function StopProcessing(app, event)
        app.loop=app.count+2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Breathing_Detector = uifigure;
            app.Breathing_Detector.Name = 'Breath Detector';
            app.UIAxes = uiaxes(app.Breathing_Detector);
            title(app.UIAxes, 'Breath Detector')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Signal')
            app.DurationsEditFieldLabel = uilabel(app.Breathing_Detector);
            app.DurationsEditFieldLabel.Text = 'Duration(s)';
            app.DurationsEditField = uieditfield(app.Breathing_Detector, 'numeric');
            app.ProcessingLampLabel = uilabel(app.Breathing_Detector);
            app.ProcessingLampLabel.Text = 'Processing';
            app.ProcessingLamp = uilamp(app.Breathing_Detector);
            app.ProcessingLamp.Enable = 'off';
            app.StartButton = uibutton(app.Breathing_Detector, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BPMTextAreaLabel = uilabel(app.Breathing_Detector);
            app.BPMTextAreaLabel.Text = 'BPM';
            app.BPMTextArea = uitextarea(app.Breathing_Detector);
            app.BPMTextArea.Value = {'0'};
            app.ClearButton = uibutton(app.Breathing_Detector, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButton, true);
            app.ClearButton.Text = 'Clear';
            app.StopButton = uibutton(app.Breathing_Detector, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopProcessing, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = matlab_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Breathing_Detector)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Breathing_Detector)
        end
    end
end